classdef groundcalc_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GroundCalc                      matlab.ui.Figure
        RunButton                       matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
        SystemgeometryPanel             matlab.ui.container.Panel
        DefaultradiusmEditFieldLabel    matlab.ui.control.Label
        DefaultradiusmEditField         matlab.ui.control.NumericEditField
        LoadCondButton                  matlab.ui.control.Button
        ConductorcoordinatesfileEditFieldLabel  matlab.ui.control.Label
        ConductorcoordinatesfileEditField  matlab.ui.control.EditField
        HelpButton                      matlab.ui.control.Button
        SoilresistivityPanel            matlab.ui.container.Panel
        ToplayerresistivitymEditFieldLabel  matlab.ui.control.Label
        ToplayerresistivitymEditField   matlab.ui.control.NumericEditField
        BottomlayerresistivitymEditFieldLabel  matlab.ui.control.Label
        BottomlayerresistivitymEditField  matlab.ui.control.NumericEditField
        ToplayerthicknessmEditFieldLabel  matlab.ui.control.Label
        ToplayerthicknessmEditField     matlab.ui.control.NumericEditField
        PowersystemPanel                matlab.ui.container.Panel
        I_im                            matlab.ui.control.NumericEditField
        jLabel                          matlab.ui.control.Label
        I_re                            matlab.ui.control.NumericEditField
        FaultcurrentALabel              matlab.ui.control.Label
        FrequencyHzEditFieldLabel       matlab.ui.control.Label
        FrequencyHzEditField            matlab.ui.control.NumericEditField
        SafelimitsPanel                 matlab.ui.container.Panel
        CoverlayerresistivitymEditFieldLabel  matlab.ui.control.Label
        CoverlayerresistivitymEditField  matlab.ui.control.NumericEditField
        CoverlayerthicknesscmLabel      matlab.ui.control.Label
        CoverlayerthicknesscmEditField  matlab.ui.control.NumericEditField
        FaultdurationmsEditFieldLabel   matlab.ui.control.Label
        FaultdurationmsEditField        matlab.ui.control.NumericEditField
        RequestplotsPanel               matlab.ui.container.Panel
        SurfacepotentialsCheckBox       matlab.ui.control.CheckBox
        CurrentdistributionCheckBox     matlab.ui.control.CheckBox
        TouchvoltagesCheckBox           matlab.ui.control.CheckBox
        StepvoltagesCheckBox            matlab.ui.control.CheckBox
    end


    methods (Access = private)
    
        function [isgood, msg] = validateFilename(app)
            fname=app.ConductorcoordinatesfileEditField.Value;
            isgood=true;
            msg='';
            if  ~exist(fname, 'file')
                msg='Invalid file name!';
                isgood=false;
                return;
            end
        end
        
    end


    methods (Access = private)

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: LoadCondButton
        function LoadCondButtonPushed(app, event)

            [file,path] = uigetfile({'*.txt';'*.csv'});
            app.GroundCalc.Visible = 'off';
            app.GroundCalc.Visible = 'on';
            if file ~=0
                fname=fullfile(path,file);
                app.ConductorcoordinatesfileEditField.Value=fname;
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            [testfile,msg]=validateFilename(app);
            if ~testfile
                f = msgbox(msg, 'You lose, fella.','error');
                return;
            end
            fname=app.ConductorcoordinatesfileEditField.Value;
            cond_radius=app.DefaultradiusmEditField.Value;
            rho_top=app.ToplayerresistivitymEditField.Value;
            rho_bottom=app.BottomlayerresistivitymEditField.Value;
            h_top=app.ToplayerthicknessmEditField.Value;
            i_src=complex(app.I_re.Value,app.I_im.Value);
            t_fault=app.FaultdurationmsEditField.Value;
            rho_cover=app.CoverlayerresistivitymEditField.Value;
            h_cover=app.CoverlayerthicknesscmEditField.Value / 100;
            plot_surf=app.SurfacepotentialsCheckBox.Value;
            plot_curr=app.CurrentdistributionCheckBox.Value;
            plot_touch=app.TouchvoltagesCheckBox.Value;
            plot_step=app.StepvoltagesCheckBox.Value;
            try
                close all;
                clc;
                addpath('functions');
                addpath('assets');
                runGroundCalcSolver(fname, cond_radius, rho_top, rho_bottom, h_top, i_src, t_fault, rho_cover, h_cover, plot_surf, plot_curr, plot_touch, plot_step);
                groundcalc_chart_control;
            catch
                f = msgbox('Error running GroundCalc solver!','You lose, fella.','error');
            end
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            f = msgbox('Provide the path to a CSV or text file containing the 3D coordinates [x, y, z] of the start point and end point of each conductor, respectively, in the format [x_s, y_s, z_s, x_e, y_e, z_e].','Help','help');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GroundCalc = uifigure;
            app.GroundCalc.Name = 'GroundCalc - A Very Simple Grounding Grid Calculator';
            app.RunButton = uibutton(app.GroundCalc, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ExitButton = uibutton(app.GroundCalc, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.SystemgeometryPanel = uipanel(app.GroundCalc);
            app.SystemgeometryPanel.Title = 'System geometry';
            app.DefaultradiusmEditFieldLabel = uilabel(app.SystemgeometryPanel);
            app.DefaultradiusmEditFieldLabel.Text = 'Default radius [m]:';
            app.DefaultradiusmEditField = uieditfield(app.SystemgeometryPanel, 'numeric');
            app.DefaultradiusmEditField.Limits = [0 Inf];
            app.DefaultradiusmEditField.Value = 0.0067056;
            app.LoadCondButton = uibutton(app.SystemgeometryPanel, 'push');
            app.LoadCondButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCondButtonPushed, true);
            app.LoadCondButton.Text = '';
            app.ConductorcoordinatesfileEditFieldLabel = uilabel(app.SystemgeometryPanel);
            app.ConductorcoordinatesfileEditFieldLabel.Text = 'Conductor coordinates [file]:';
            app.ConductorcoordinatesfileEditField = uieditfield(app.SystemgeometryPanel, 'text');
            app.ConductorcoordinatesfileEditField.Editable = 'off';
            app.HelpButton = uibutton(app.SystemgeometryPanel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = '?';
            app.SoilresistivityPanel = uipanel(app.GroundCalc);
            app.SoilresistivityPanel.Title = 'Soil resistivity';
            app.ToplayerresistivitymEditFieldLabel = uilabel(app.SoilresistivityPanel);
            app.ToplayerresistivitymEditFieldLabel.Text = 'Top layer resistivity [Ω.m]:';
            app.ToplayerresistivitymEditField = uieditfield(app.SoilresistivityPanel, 'numeric');
            app.ToplayerresistivitymEditField.Limits = [0 Inf];
            app.ToplayerresistivitymEditField.Value = 1000;
            app.BottomlayerresistivitymEditFieldLabel = uilabel(app.SoilresistivityPanel);
            app.BottomlayerresistivitymEditFieldLabel.Text = 'Bottom layer resistivity [Ω.m]:';
            app.BottomlayerresistivitymEditField = uieditfield(app.SoilresistivityPanel, 'numeric');
            app.BottomlayerresistivitymEditField.Limits = [0 Inf];
            app.BottomlayerresistivitymEditField.Value = 100;
            app.ToplayerthicknessmEditFieldLabel = uilabel(app.SoilresistivityPanel);
            app.ToplayerthicknessmEditFieldLabel.Text = 'Top layer thickness [m]:';
            app.ToplayerthicknessmEditField = uieditfield(app.SoilresistivityPanel, 'numeric');
            app.ToplayerthicknessmEditField.Limits = [0 Inf];
            app.ToplayerthicknessmEditField.Value = 1;
            app.PowersystemPanel = uipanel(app.GroundCalc);
            app.PowersystemPanel.Title = 'Power system';
            app.I_im = uieditfield(app.PowersystemPanel, 'numeric');
            app.I_im.Limits = [0 Inf];
            app.jLabel = uilabel(app.PowersystemPanel);
            app.jLabel.Text = '+j';
            app.I_re = uieditfield(app.PowersystemPanel, 'numeric');
            app.I_re.Limits = [0 Inf];
            app.I_re.Value = 3000;
            app.FaultcurrentALabel = uilabel(app.PowersystemPanel);
            app.FaultcurrentALabel.Text = 'Fault current [A]:';
            app.FrequencyHzEditFieldLabel = uilabel(app.PowersystemPanel);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency [Hz]:';
            app.FrequencyHzEditField = uieditfield(app.PowersystemPanel, 'numeric');
            app.FrequencyHzEditField.Limits = [0 Inf];
            app.FrequencyHzEditField.Editable = 'off';
            app.FrequencyHzEditField.Value = 60;
            app.SafelimitsPanel = uipanel(app.GroundCalc);
            app.SafelimitsPanel.Title = 'Safe limits';
            app.CoverlayerresistivitymEditFieldLabel = uilabel(app.SafelimitsPanel);
            app.CoverlayerresistivitymEditFieldLabel.Text = 'Cover layer resistivity [Ω.m]:';
            app.CoverlayerresistivitymEditField = uieditfield(app.SafelimitsPanel, 'numeric');
            app.CoverlayerresistivitymEditField.Limits = [0 Inf];
            app.CoverlayerresistivitymEditField.Value = 3000;
            app.CoverlayerthicknesscmLabel = uilabel(app.SafelimitsPanel);
            app.CoverlayerthicknesscmLabel.Text = 'Cover layer thickness [cm]:';
            app.CoverlayerthicknesscmEditField = uieditfield(app.SafelimitsPanel, 'numeric');
            app.CoverlayerthicknesscmEditField.Limits = [0 Inf];
            app.FaultdurationmsEditFieldLabel = uilabel(app.SafelimitsPanel);
            app.FaultdurationmsEditFieldLabel.Text = 'Fault duration [ms]:';
            app.FaultdurationmsEditField = uieditfield(app.SafelimitsPanel, 'numeric');
            app.FaultdurationmsEditField.Limits = [0 Inf];
            app.FaultdurationmsEditField.Value = 1000;
            app.RequestplotsPanel = uipanel(app.GroundCalc);
            app.RequestplotsPanel.Title = 'Request plots';
            app.SurfacepotentialsCheckBox = uicheckbox(app.RequestplotsPanel);
            app.SurfacepotentialsCheckBox.Text = 'Surface potentials';
            app.SurfacepotentialsCheckBox.Value = true;
            app.CurrentdistributionCheckBox = uicheckbox(app.RequestplotsPanel);
            app.CurrentdistributionCheckBox.Text = 'Current distribution';
            app.CurrentdistributionCheckBox.Value = true;
            app.TouchvoltagesCheckBox = uicheckbox(app.RequestplotsPanel);
            app.TouchvoltagesCheckBox.Text = 'Touch voltages';
            app.TouchvoltagesCheckBox.Value = true;
            app.StepvoltagesCheckBox = uicheckbox(app.RequestplotsPanel);
            app.StepvoltagesCheckBox.Text = 'Step voltages';
            app.StepvoltagesCheckBox.Value = true;
        end
    end

    methods (Access = public)

        % Construct app
        function app = groundcalc_main

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GroundCalc)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GroundCalc)
        end
    end
end